classdef appTFM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel_11                        matlab.ui.container.Panel
        Panel_14                        matlab.ui.container.Panel
        sLabel                          matlab.ui.control.Label
        CoilRiseTimeEditField           matlab.ui.control.NumericEditField
        CoilRiseTimeEditFieldLabel      matlab.ui.control.Label
        NeccessarywhenusingasquaresignalLabel  matlab.ui.control.Label
        Panel_13                        matlab.ui.container.Panel
        NewCoilNameEditField            matlab.ui.control.EditField
        NewCoilNameEditFieldLabel       matlab.ui.control.Label
        Panel_12                        matlab.ui.container.Panel
        ResetButton                     matlab.ui.control.Button
        AddNewCoiltoDDBBButton          matlab.ui.control.Button
        CorePanel_2                     matlab.ui.container.Panel
        mLabel_14                       matlab.ui.control.Label
        mLabel_9                        matlab.ui.control.Label
        DiameterEditField_2             matlab.ui.control.NumericEditField
        DiameterEditField_2Label        matlab.ui.control.Label
        LengthEditField_2               matlab.ui.control.NumericEditField
        LengthEditField_2Label          matlab.ui.control.Label
        CoilPanel_2                     matlab.ui.container.Panel
        mLabel_13                       matlab.ui.control.Label
        mLabel_12                       matlab.ui.control.Label
        mLabel_11                       matlab.ui.control.Label
        mLabel_10                       matlab.ui.control.Label
        HLabel_4                        matlab.ui.control.Label
        mLabel_7                        matlab.ui.control.Label
        mLabel_6                        matlab.ui.control.Label
        PitchinnerhelixEditField_2      matlab.ui.control.NumericEditField
        PitchinnerhelixEditField_2Label  matlab.ui.control.Label
        RadiusinnerhelixEditField_2     matlab.ui.control.NumericEditField
        RadiusinnerhelixEditField_2Label  matlab.ui.control.Label
        NturnsinnerhelixEditField_2     matlab.ui.control.NumericEditField
        NturnsinnerhelixEditField_2Label  matlab.ui.control.Label
        PitchouterhelixEditField_2      matlab.ui.control.NumericEditField
        PitchouterhelixEditField_2Label  matlab.ui.control.Label
        RadiusouterhelixEditField_2     matlab.ui.control.NumericEditField
        RadiusouterhelixEditField_2Label  matlab.ui.control.Label
        NturnsouterhelixEditField_2     matlab.ui.control.NumericEditField
        NturnsouterhelixEditField_2Label  matlab.ui.control.Label
        RealInductanceEditField_2       matlab.ui.control.NumericEditField
        RealInductanceEditField_2Label  matlab.ui.control.Label
        HeightEditField_2               matlab.ui.control.NumericEditField
        HeightEditField_2Label          matlab.ui.control.Label
        WireDiameterEditField_2         matlab.ui.control.NumericEditField
        WireDiameterEditField_2Label    matlab.ui.control.Label
        NewCoilParametersConfigurationLabel  matlab.ui.control.Label
        Panel_10                        matlab.ui.container.Panel
        Panel_9                         matlab.ui.container.Panel
        FolderNameEditField             matlab.ui.control.EditField
        FolderNameEditFieldLabel        matlab.ui.control.Label
        SaveResultsButton               matlab.ui.control.Button
        Panel_8                         matlab.ui.container.Panel
        ClearallButton                  matlab.ui.control.Button
        Step5StorageofResultsLabel      matlab.ui.control.Label
        Panel_7                         matlab.ui.container.Panel
        ZEditField_4Label               matlab.ui.control.Label
        YEditField_4Label               matlab.ui.control.Label
        XEditField_4Label               matlab.ui.control.Label
        ZEditField_3Label               matlab.ui.control.Label
        YEditField_3Label               matlab.ui.control.Label
        XEditField_3Label               matlab.ui.control.Label
        mmLabel_4                       matlab.ui.control.Label
        mmLabel_3                       matlab.ui.control.Label
        mmLabel_2                       matlab.ui.control.Label
        ZEditField_4                    matlab.ui.control.NumericEditField
        mmLabel                         matlab.ui.control.Label
        YEditField_4                    matlab.ui.control.NumericEditField
        XEditField_4                    matlab.ui.control.NumericEditField
        CrossSectionPositionLabel       matlab.ui.control.Label
        CrossSectionPlaneDropDown       matlab.ui.control.DropDown
        ZEditField_3                    matlab.ui.control.NumericEditField
        CrossSectionPlaneDropDownLabel  matlab.ui.control.Label
        YEditField_3                    matlab.ui.control.NumericEditField
        XEditField_3                    matlab.ui.control.NumericEditField
        CoilPositionCoordinatesLabel    matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        Step6ReturntoStartLabel         matlab.ui.control.Label
        Step4ElectricFieldCalculationLabel  matlab.ui.control.Label
        Panel_6                         matlab.ui.container.Panel
        Panel_2                         matlab.ui.container.Panel
        HLabel_3                        matlab.ui.control.Label
        HLabel_2                        matlab.ui.control.Label
        HLabel                          matlab.ui.control.Label
        TotalInductanceEditField        matlab.ui.control.EditField
        TotalInductanceEditFieldLabel   matlab.ui.control.Label
        CoreInductanceEditField         matlab.ui.control.EditField
        CoreInductanceEditFieldLabel    matlab.ui.control.Label
        CoilInductanceEditField         matlab.ui.control.EditField
        CoilInductanceEditFieldLabel    matlab.ui.control.Label
        ExecuteButton                   matlab.ui.control.Button
        Panel_5                         matlab.ui.container.Panel
        CoilNameDropDown                matlab.ui.control.DropDown
        CoilNameDropDownLabel           matlab.ui.control.Label
        DefaultValuesButton             matlab.ui.control.Button
        Panel_4                         matlab.ui.container.Panel
        UpdateValuesButton              matlab.ui.control.Button
        CorePanel                       matlab.ui.container.Panel
        mLabel_5                        matlab.ui.control.Label
        mLabel_4                        matlab.ui.control.Label
        MaterialDropDown                matlab.ui.control.DropDown
        MaterialDropDownLabel           matlab.ui.control.Label
        DiameterEditField               matlab.ui.control.NumericEditField
        DiameterEditFieldLabel          matlab.ui.control.Label
        LengthEditField                 matlab.ui.control.NumericEditField
        LengthEditFieldLabel            matlab.ui.control.Label
        CoilPanel                       matlab.ui.container.Panel
        mLabel_3                        matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        PitchinnerhelixEditField        matlab.ui.control.NumericEditField
        PitchinnerhelixEditFieldLabel   matlab.ui.control.Label
        RadiusinnerhelixEditField       matlab.ui.control.NumericEditField
        RadiusinnerhelixEditFieldLabel  matlab.ui.control.Label
        PitchouterhelixEditField        matlab.ui.control.NumericEditField
        PitchouterhelixEditFieldLabel   matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        HzLabel                         matlab.ui.control.Label
        ALabel                          matlab.ui.control.Label
        FrequencyEditField              matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel         matlab.ui.control.Label
        CurrentEditField                matlab.ui.control.NumericEditField
        CurrentEditFieldLabel           matlab.ui.control.Label
        SignalTypeDropDown              matlab.ui.control.DropDown
        SignalTypeDropDownLabel         matlab.ui.control.Label
        Step3ExecutionandDisplayofResultsLabel  matlab.ui.control.Label
        Step2CoilParametersConfigurationLabel  matlab.ui.control.Label
        Step1SignalParametersConfigurationLabel  matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            baseDir = fileparts(mfilename('fullpath'));
            coilProjectDir = fullfile(baseDir, '..', 'Pruebas', 'Matlab', 'Coil', 'CoilProject');
            addpath(coilProjectDir);
            
            % Read the JSON file
            jsonData = fileread('getCoilType.json');
            bobinas = jsondecode(jsonData);
            
            % Extract the field names (bobina types) dynamically
            bobinaTypes = fieldnames(bobinas);
            
            if isempty(bobinaTypes)
                app.CoilNameDropDown.Items = {'No bobinas found'};
                app.CoilNameDropDown.Value = 'No bobinas found';
                warning('No bobina types found in JSON.');
            else
                % Update the TipoBobinaDropDown dropdown with the dynamic list
                app.CoilNameDropDown.Items = bobinaTypes;
                app.CoilNameDropDown.Value = bobinaTypes{1}; % Set first item as default
            end
        end

        % Button pushed function: DefaultValuesButton
        function DefaultValuesButtonPushed(app, event)
            % Get the selected TipoBobina value
            tipoBobina = app.CoilNameDropDown.Value;
            
            % Read the JSON file
            jsonDataCoil = fileread('getCoilType.json');
            jsonDataCore = fileread('getCoreType.json');

            coils = jsondecode(jsonDataCoil);
            cores = jsondecode(jsonDataCore);
            
            % Load the values for the selected bobina
            if isfield(coils, tipoBobina)
                app.PitchouterhelixEditField.Value = coils.(tipoBobina).pitch1;
                app.RadiusinnerhelixEditField.Value = coils.(tipoBobina).radius2;
                app.PitchinnerhelixEditField.Value = coils.(tipoBobina).pitch2;
                app.LengthEditField.Value = cores.(tipoBobina).L;
                app.DiameterEditField.Value = cores.(tipoBobina).wd;
            else
                % Handle case where the selected bobina is not found
                app.WireDiameterEditField.Value = 0;
                app.HeightEditField.Value = 0;
                app.RealInductanceEditField.Value = 0;
                app.NturnsouterhelixEditField.Value = 0;
                app.RadiusouterhelixEditField.Value = 0;
                app.PitchouterhelixEditField.Value = 0;
                app.NturnsinnerhelixEditField.Value = 0;
                app.RadiusinnerhelixEditField.Value = 0;
                app.PitchinnerhelixEditField.Value = 0;
                app.LengthEditField.Value = 0;
                app.DiameterEditField.Value = 0;
                disp(['No data found for ' tipoBobina]);
            end
        end

        % Button pushed function: UpdateValuesButton
        function UpdateValuesButtonPushed(app, event)
            % Get the selected TipoBobina value
            tipoBobina = app.CoilNameDropDown.Value;
            
            % Read the JSON file
            jsonDataCoil = fileread('getCoilType.json');
            jsonDataCore = fileread('getCoreType.json');
            coils = jsondecode(jsonDataCoil);
            cores = jsondecode(jsonDataCore);
            
            % Update the values for the selected bobina
            coils.(tipoBobina).pitch1 = app.PitchouterhelixEditField.Value;
            coils.(tipoBobina).radius2 = app.RadiusinnerhelixEditField.Value;
            coils.(tipoBobina).pitch2 = app.PitchinnerhelixEditField.Value;
            cores.(tipoBobina).L = app.LengthEditField.Value;
            cores.(tipoBobina).wd = app.DiameterEditField.Value;
           
            % Convert back to JSON and save to file
            jsonTextCoils = jsonencode(coils, 'PrettyPrint', true);
            jsonTextCores = jsonencode(cores, 'PrettyPrint', true);
            fidCoils = fopen('getCoilType.json', 'w');
            fidCores = fopen('getCoreType.json', 'w');
            if fidCoils == -1, error('Cannot open file for writing'); end
            if fidCores == -1, error('Cannot open file for writing'); end
            fwrite(fidCoils, jsonTextCoils);
            fclose(fidCoils);
            fwrite(fidCores, jsonTextCores);
            fclose(fidCores);
            
            disp(['Values updated for ' tipoBobina]);
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            CoilName = app.CoilNameDropDown.Value;
            SignalType = app.SignalTypeDropDown.Value;
            CoreMaterial = app.MaterialDropDown.Value;
            current = app.CurrentEditField.Value;
            frequency = app.FrequencyEditField.Value;

            % 2) Verify signal selection
            try
                SignalType = validatestring(string(SignalType), {'Sinusoidal','Square'});
            catch
                uialert(app.UIFigure, ...
                    'Select a valid signal before continuing.', ...
                    'Aviso', 'Icon', 'warning');
                return;
            end

            % 2) Verify core material selection
            try
                CoreMaterial = validatestring(string(CoreMaterial), {'m3_atan','m3_froe','gen_atan','met_froe'});
            catch
                uialert(app.UIFigure, ...
                    'Select a valid core material before continuing.', ...
                    'Aviso', 'Icon', 'warning');
                return;
            end

            % Create a progress dialog
            progressDlg = uiprogressdlg(app.UIFigure, 'Title', 'Please Wait', ...
            'Message', 'Calculating inductance... This may take a moment.', ...
            'Indeterminate', 'on');

            coil_inductance_PGH_func(CoilName, SignalType, CoreMaterial, current, frequency);

            close(progressDlg);

            load('coil_inductance_workspace.mat', 'Inductance');

            % Assign values to edit fields
            app.CoilInductanceEditField.Value = num2str(Inductance.Lpri);
            app.CoreInductanceEditField.Value = num2str(Inductance.Lsec);
            app.TotalInductanceEditField.Value = num2str(Inductance.L);

            uialert(app.UIFigure, 'Execution completed successfully!', 'Success', 'Icon', 'info');
        end

        % Button pushed function: ClearallButton
        function ClearallButtonPushed(app, event)
            % Reset drop-downs to their first items
            
            close all;

            coilNames = app.CoilNameDropDown.Items;
            if ~isempty(coilNames)
                app.CoilNameDropDown.Value = coilNames{1}; % Set first item as default
            end
    
            signalTypes = app.SignalTypeDropDown.Items;
            if ~isempty(signalTypes)
                app.SignalTypeDropDown.Value = signalTypes{1}; % Set first item as default
            end
    
            materialTypes = app.MaterialDropDown.Items;
            if ~isempty(materialTypes)
                app.MaterialDropDown.Value = materialTypes{1}; % Set first item as default
            end

            app.CurrentEditField.Value = 0;
            app.FrequencyEditField.Value = 0;

            app.PitchouterhelixEditField.Value = 0;
            app.RadiusinnerhelixEditField.Value = 0;
            app.PitchinnerhelixEditField.Value = 0;
            app.LengthEditField.Value = 0;
            app.DiameterEditField.Value = 0;

            app.CoilInductanceEditField.Value = '';
            app.CoreInductanceEditField.Value = '';
            app.TotalInductanceEditField.Value = '';

            app.FolderNameEditField.Value = '';

            app.XEditField_3.Value = 0;
            app.YEditField_3.Value = 0;
            app.ZEditField_3.Value = 0;
            app.CrossSectionPlaneDropDown.Value = 'Select...';
            app.XEditField_4.Value = 0;
            app.YEditField_4.Value = 0;
            app.ZEditField_4.Value = 0;

            % Al final de ClearallButtonPushed, reemplaza todo lo relacionado con rutas por:
            projDir = fileparts(mfilename('fullpath'));   % C:\...\Pruebas\Matlab\Coil\CoilProject

            % Añadir al path solo si no está ya
            if ~any(strcmpi(strsplit(path, pathsep), projDir))
                addpath(projDir);
            end
            
            % Volver al directorio del proyecto
            if ~strcmpi(pwd, projDir)
                try
                    cd(projDir);
                catch ME
                    uialert(app.UIFigure, sprintf('Could not change to the project directory:\n%s', ME.message), ...
                        'Error', 'Icon', 'error');
                end
            end

            % Display confirmation
            disp('Workspace cleared and fields reset.');
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            coil_pos_coordinates_X = app.XEditField_3.Value;
            coil_pos_coordinates_Y = app.YEditField_3.Value;
            coil_pos_coordinates_Z = app.ZEditField_3.Value;
            cross_section_plane = app.CrossSectionPlaneDropDown.Value;
            cross_section_pos_X = app.XEditField_4.Value;
            cross_section_pos_Y = app.YEditField_4.Value;
            cross_section_pos_Z = app.ZEditField_4.Value;
            
            % 2) Verify plane selection
            try
                cross_section_plane = validatestring(string(cross_section_plane), {'Coronal','Sagittal'});
            catch
                uialert(app.UIFigure, ...
                    'Select a valid cross-section plane before continuing.', ...
                    'Aviso', 'Icon', 'warning');
                return;
            end

            % Create a progress dialog
            progressDlg = uiprogressdlg(app.UIFigure, 'Title', 'Please Wait', ...
            'Message', 'Calculating electric field... This may take a moment.', ...
            'Indeterminate', 'on');

            try
                bem_PGH_func(coil_pos_coordinates_X, coil_pos_coordinates_Y, coil_pos_coordinates_Z, cross_section_plane, cross_section_pos_X, cross_section_pos_Y, cross_section_pos_Z);
                close(progressDlg);
                uialert(app.UIFigure, 'Execution completed successfully!', 'Success', 'Icon', 'info');
            catch e
                uialert(app.UIFigure, getReport(e, 'basic', 'hyperlinks', 'off'), ...
                'Error during execution', 'Icon', 'error');
            end
        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            figs = setdiff(findall(0, 'Type', 'figure'), app.UIFigure);
            if isempty(figs)
                uialert(app.UIFigure, ...
                    'There are no open figures to save.', ...
                    'Warning', 'Icon', 'warning');
                return;
            end
            % Obtener el nombre de la carpeta y la ruta de los Edit Fields
            folderName = app.FolderNameEditField.Value;  % Nombre de la carpeta
            % Verificar que el nombre no esté vacío
            if isempty(folderName)
                uialert(app.UIFigure, 'Please enter a valid folder name.', 'Warning', 'Icon', 'warning');
                return;
            end

            folderPath = uigetdir('', 'Select folder to save images.');
            % Verificar si el usuario seleccionó una carpeta
            if isequal(folderPath, 0)
                uialert(app.UIFigure, 'No folder was selected.', 'Warning', 'Icon', 'warning');
                return;
            end
            
            % Crear la carpeta si no existe
            newFilePath = fullfile(folderPath, folderName);
            if ~exist(newFilePath, 'dir')
                try
                    mkdir(newFilePath);
                    disp(['Folder created in: ', newFilePath]);
                catch ME
                    uialert(app.UIFigure, sprintf('The folder could not be created:\n%s', ME.message), 'Error', 'Icon', 'error');
                    return;
                end
            else
                disp('The folder already exists. Please choose a different name.');
            end
            
            % Obtener todas las figuras abiertas
            figs = findall(0, 'Type', 'figure');  % Encuentra todas las figuras abiertas
            
            % Verificar si hay figuras abiertas
            if isempty(figs)
                uialert(app.UIFigure, 'There are no open figures to save.', 'Warning');
                return;
            end

            % Guardar todas las figuras .fig de forma numerada
            n = numel(figs);
            guardadas = 0;
            for k = 1:n
                filename = fullfile(newFilePath, sprintf('figura_%02d.fig', k));
                try
                    savefig(figs(k), filename);
                    disp(['in: ', filename]);
                    guardadas = guardadas + 1;
                catch ME
                    uialert(app.UIFigure, ...
                        sprintf('Error saving figure %d:\n%s', k, ME.message), ...
                        'Error', 'Icon', 'error');
                end
            end
        
            % 6) Mensaje final (éxito/mixto)
            if guardadas == 0
                uialert(app.UIFigure, 'No figures could be saved.', 'Warning', 'Icon', 'warning');
            else
                uialert(app.UIFigure, ...
                    sprintf('%d figures saved in %s', guardadas, newFilePath), ...
                    'Éxito', 'Icon', 'success');
            end


            % % Ahora, generar el archivo de texto en la misma carpeta
            % % Cambiar la ruta y nombre del archivo
            % out_path = fullfile(newFilePath, 'summary.txt');
            % 
            % % Llamar al script que guarda el resumen
            % saveSummary(out_path);
        end

        % Button pushed function: AddNewCoiltoDDBBButton
        function AddNewCoiltoDDBBButtonPushed(app, event)
            % Get the selected TipoBobina value
            NewCoilName = app.NewCoilNameEditField.Value;
            
            % Read the JSON file
            jsonDataCoil = fileread('getCoilType.json');
            jsonDataCore = fileread('getCoreType.json');
            coils = jsondecode(jsonDataCoil);
            cores = jsondecode(jsonDataCore);
            
            % Check if the coil type already exists. If it does, exit the function.
            if isfield(coils, NewCoilName)
                disp(['The coil type "', NewCoilName, '" already exists. Exiting function.']);
                return;  % Exit the function if the coil type already exists
            end
            
            % If the coil type doesn't exist, proceed with adding the new coil
            if ~isfield(coils, NewCoilName)
                coils.(NewCoilName) = struct();  % Create a new empty struct for the new coil type
            end
            if ~isfield(cores, NewCoilName)
                cores.(NewCoilName) = struct();  % Create a new empty struct for the new core type
            end
            
            % Update the values for the selected bobina
            coils.(NewCoilName).t_fall = app.CoilRiseTimeEditField.Value;
            coils.(NewCoilName).wd = app.WireDiameterEditField_2.Value;
            coils.(NewCoilName).h = app.HeightEditField_2.Value;
            coils.(NewCoilName).L = app.RealInductanceEditField_2.Value;
            coils.(NewCoilName).turns1 = app.NturnsouterhelixEditField_2.Value;
            coils.(NewCoilName).radius1 = app.RadiusouterhelixEditField_2.Value;
            coils.(NewCoilName).pitch1 = app.PitchouterhelixEditField_2.Value;
            coils.(NewCoilName).turns2 = app.NturnsinnerhelixEditField_2.Value;
            coils.(NewCoilName).radius2 = app.RadiusinnerhelixEditField_2.Value;
            coils.(NewCoilName).pitch2 = app.PitchinnerhelixEditField_2.Value;
            cores.(NewCoilName).L = app.LengthEditField_2.Value;
            cores.(NewCoilName).wd = app.DiameterEditField_2.Value;
           
            % Convert back to JSON and save to file
            jsonTextCoils = jsonencode(coils, 'PrettyPrint', true);
            jsonTextCores = jsonencode(cores, 'PrettyPrint', true);
            
            % Open files for writing
            fidCoils = fopen('getCoilType.json', 'w');
            fidCores = fopen('getCoreType.json', 'w');
            
            % Check if files were opened successfully
            if fidCoils == -1, error('Cannot open file for writing: getCoilType.json'); end
            if fidCores == -1, error('Cannot open file for writing: getCoreType.json'); end
            
            % Write the updated data to the files
            fwrite(fidCoils, jsonTextCoils);
            fclose(fidCoils);
            
            fwrite(fidCores, jsonTextCores);
            fclose(fidCores);
            
            disp(['Values added for new ', NewCoilName]);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.NewCoilNameEditField.Value = ' ';
            app.CoilRiseTimeEditField.Value = 0;
            app.WireDiameterEditField_2.Value = 0;
            app.HeightEditField_2.Value = 0;
            app.RealInductanceEditField_2.Value = 0;
            app.NturnsouterhelixEditField_2.Value = 0;
            app.NturnsinnerhelixEditField_2.Value = 0;
            app.RadiusouterhelixEditField_2.Value = 0;
            app.RadiusinnerhelixEditField_2.Value = 0;
            app.PitchinnerhelixEditField_2.Value = 0;
            app.PitchouterhelixEditField_2.Value = 0;
            app.LengthEditField_2.Value = 0;
            app.WireDiameterEditField_2.Value = 0;

            % Display confirmation
            disp('Fields reset.');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel_10 = uipanel(app.UIFigure);
            app.Step1SignalParametersConfigurationLabel = uilabel(app.Panel_10);
            app.Step1SignalParametersConfigurationLabel.Text = 'Step 1: Signal Parameters Configuration';
            app.Step2CoilParametersConfigurationLabel = uilabel(app.Panel_10);
            app.Step2CoilParametersConfigurationLabel.Text = 'Step 2: Coil Parameters Configuration';
            app.Step3ExecutionandDisplayofResultsLabel = uilabel(app.Panel_10);
            app.Step3ExecutionandDisplayofResultsLabel.Text = 'Step 3: Execution and Display of Results';
            app.Panel_3 = uipanel(app.Panel_10);
            app.SignalTypeDropDownLabel = uilabel(app.Panel_3);
            app.SignalTypeDropDownLabel.Text = 'Signal Type';
            app.SignalTypeDropDown = uidropdown(app.Panel_3);
            app.SignalTypeDropDown.Items = {'Select...', 'Sinusoidal', 'Square'};
            app.SignalTypeDropDown.Value = 'Select...';
            app.CurrentEditFieldLabel = uilabel(app.Panel_3);
            app.CurrentEditFieldLabel.Text = 'Current';
            app.CurrentEditField = uieditfield(app.Panel_3, 'numeric');
            app.FrequencyEditFieldLabel = uilabel(app.Panel_3);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.Panel_3, 'numeric');
            app.ALabel = uilabel(app.Panel_3);
            app.ALabel.Text = '(A)';
            app.HzLabel = uilabel(app.Panel_3);
            app.HzLabel.Text = '(Hz)';
            app.Panel_4 = uipanel(app.Panel_10);
            app.CoilPanel = uipanel(app.Panel_4);
            app.CoilPanel.Title = 'Coil';
            app.PitchouterhelixEditFieldLabel = uilabel(app.CoilPanel);
            app.PitchouterhelixEditFieldLabel.Text = 'Pitch outer helix';
            app.PitchouterhelixEditField = uieditfield(app.CoilPanel, 'numeric');
            app.RadiusinnerhelixEditFieldLabel = uilabel(app.CoilPanel);
            app.RadiusinnerhelixEditFieldLabel.Text = 'Radius inner helix';
            app.RadiusinnerhelixEditField = uieditfield(app.CoilPanel, 'numeric');
            app.PitchinnerhelixEditFieldLabel = uilabel(app.CoilPanel);
            app.PitchinnerhelixEditFieldLabel.Text = 'Pitch inner helix';
            app.PitchinnerhelixEditField = uieditfield(app.CoilPanel, 'numeric');
            app.mLabel = uilabel(app.CoilPanel);
            app.mLabel.Text = '(m)';
            app.mLabel_2 = uilabel(app.CoilPanel);
            app.mLabel_2.Text = '(m)';
            app.mLabel_3 = uilabel(app.CoilPanel);
            app.mLabel_3.Text = '(m)';
            app.CorePanel = uipanel(app.Panel_4);
            app.CorePanel.Title = 'Core';
            app.LengthEditFieldLabel = uilabel(app.CorePanel);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.CorePanel, 'numeric');
            app.DiameterEditFieldLabel = uilabel(app.CorePanel);
            app.DiameterEditFieldLabel.Text = 'Diameter';
            app.DiameterEditField = uieditfield(app.CorePanel, 'numeric');
            app.MaterialDropDownLabel = uilabel(app.CorePanel);
            app.MaterialDropDownLabel.Text = 'Material';
            app.MaterialDropDown = uidropdown(app.CorePanel);
            app.MaterialDropDown.Items = {'Select...', 'm3_atan', 'm3_froe', 'gen_atan', 'met_froe'};
            app.MaterialDropDown.Value = 'Select...';
            app.mLabel_4 = uilabel(app.CorePanel);
            app.mLabel_4.Text = '(m)';
            app.mLabel_5 = uilabel(app.CorePanel);
            app.mLabel_5.Text = '(m)';
            app.UpdateValuesButton = uibutton(app.Panel_4, 'push');
            app.UpdateValuesButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateValuesButtonPushed, true);
            app.UpdateValuesButton.Text = 'Update Values';
            app.Panel_5 = uipanel(app.Panel_10);
            app.DefaultValuesButton = uibutton(app.Panel_5, 'push');
            app.DefaultValuesButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultValuesButtonPushed, true);
            app.DefaultValuesButton.Text = 'Default Values';
            app.CoilNameDropDownLabel = uilabel(app.Panel_5);
            app.CoilNameDropDownLabel.Text = 'Coil Name';
            app.CoilNameDropDown = uidropdown(app.Panel_5);
            app.Panel_6 = uipanel(app.Panel_10);
            app.ExecuteButton = uibutton(app.Panel_6, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.Panel_2 = uipanel(app.Panel_6);
            app.CoilInductanceEditFieldLabel = uilabel(app.Panel_2);
            app.CoilInductanceEditFieldLabel.Text = 'Coil Inductance';
            app.CoilInductanceEditField = uieditfield(app.Panel_2, 'text');
            app.CoilInductanceEditField.Editable = 'off';
            app.CoreInductanceEditFieldLabel = uilabel(app.Panel_2);
            app.CoreInductanceEditFieldLabel.Text = 'Core Inductance  ';
            app.CoreInductanceEditField = uieditfield(app.Panel_2, 'text');
            app.CoreInductanceEditField.Editable = 'off';
            app.TotalInductanceEditFieldLabel = uilabel(app.Panel_2);
            app.TotalInductanceEditFieldLabel.Text = 'Total Inductance';
            app.TotalInductanceEditField = uieditfield(app.Panel_2, 'text');
            app.TotalInductanceEditField.Editable = 'off';
            app.HLabel = uilabel(app.Panel_2);
            app.HLabel.Text = '(H)';
            app.HLabel_2 = uilabel(app.Panel_2);
            app.HLabel_2.Text = '(H)';
            app.HLabel_3 = uilabel(app.Panel_2);
            app.HLabel_3.Text = '(H)';
            app.Step4ElectricFieldCalculationLabel = uilabel(app.Panel_10);
            app.Step4ElectricFieldCalculationLabel.Text = 'Step 4: Electric Field Calculation';
            app.Step6ReturntoStartLabel = uilabel(app.Panel_10);
            app.Step6ReturntoStartLabel.Text = 'Step 6: Return to Start';
            app.Panel_7 = uipanel(app.Panel_10);
            app.CalculateButton = uibutton(app.Panel_7, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.CoilPositionCoordinatesLabel = uilabel(app.Panel_7);
            app.CoilPositionCoordinatesLabel.Text = 'Coil Position Coordinates';
            app.XEditField_3 = uieditfield(app.Panel_7, 'numeric');
            app.YEditField_3 = uieditfield(app.Panel_7, 'numeric');
            app.CrossSectionPlaneDropDownLabel = uilabel(app.Panel_7);
            app.CrossSectionPlaneDropDownLabel.Text = 'Cross-Section Plane';
            app.ZEditField_3 = uieditfield(app.Panel_7, 'numeric');
            app.CrossSectionPlaneDropDown = uidropdown(app.Panel_7);
            app.CrossSectionPlaneDropDown.Items = {'Select...', 'Coronal', 'Sagittal'};
            app.CrossSectionPlaneDropDown.Value = 'Select...';
            app.CrossSectionPositionLabel = uilabel(app.Panel_7);
            app.CrossSectionPositionLabel.Text = 'Cross-Section Position';
            app.XEditField_4 = uieditfield(app.Panel_7, 'numeric');
            app.YEditField_4 = uieditfield(app.Panel_7, 'numeric');
            app.mmLabel = uilabel(app.Panel_7);
            app.mmLabel.Text = '(mm)';
            app.ZEditField_4 = uieditfield(app.Panel_7, 'numeric');
            app.mmLabel_2 = uilabel(app.Panel_7);
            app.mmLabel_2.Text = '(mm)';
            app.mmLabel_3 = uilabel(app.Panel_7);
            app.mmLabel_3.Text = '(mm)';
            app.mmLabel_4 = uilabel(app.Panel_7);
            app.mmLabel_4.Text = '(mm)';
            app.XEditField_3Label = uilabel(app.Panel_7);
            app.XEditField_3Label.Text = 'X';
            app.YEditField_3Label = uilabel(app.Panel_7);
            app.YEditField_3Label.Text = 'Y';
            app.ZEditField_3Label = uilabel(app.Panel_7);
            app.ZEditField_3Label.Text = 'Z';
            app.XEditField_4Label = uilabel(app.Panel_7);
            app.XEditField_4Label.Text = 'X';
            app.YEditField_4Label = uilabel(app.Panel_7);
            app.YEditField_4Label.Text = 'Y';
            app.ZEditField_4Label = uilabel(app.Panel_7);
            app.ZEditField_4Label.Text = 'Z';
            app.Step5StorageofResultsLabel = uilabel(app.Panel_10);
            app.Step5StorageofResultsLabel.Text = 'Step 5: Storage of Results';
            app.Panel_8 = uipanel(app.Panel_10);
            app.ClearallButton = uibutton(app.Panel_8, 'push');
            app.ClearallButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallButtonPushed, true);
            app.ClearallButton.Text = 'Clear all';
            app.Panel_9 = uipanel(app.Panel_10);
            app.SaveResultsButton = uibutton(app.Panel_9, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Text = 'Save Results';
            app.FolderNameEditFieldLabel = uilabel(app.Panel_9);
            app.FolderNameEditFieldLabel.Text = 'Folder Name';
            app.FolderNameEditField = uieditfield(app.Panel_9, 'text');
            app.Panel_11 = uipanel(app.UIFigure);
            app.NewCoilParametersConfigurationLabel = uilabel(app.Panel_11);
            app.NewCoilParametersConfigurationLabel.Text = 'New Coil Parameters Configuration';
            app.Panel_12 = uipanel(app.Panel_11);
            app.CoilPanel_2 = uipanel(app.Panel_12);
            app.CoilPanel_2.Title = 'Coil';
            app.WireDiameterEditField_2Label = uilabel(app.CoilPanel_2);
            app.WireDiameterEditField_2Label.Text = 'Wire Diameter';
            app.WireDiameterEditField_2 = uieditfield(app.CoilPanel_2, 'numeric');
            app.HeightEditField_2Label = uilabel(app.CoilPanel_2);
            app.HeightEditField_2Label.Text = 'Height';
            app.HeightEditField_2 = uieditfield(app.CoilPanel_2, 'numeric');
            app.RealInductanceEditField_2Label = uilabel(app.CoilPanel_2);
            app.RealInductanceEditField_2Label.Text = 'Real Inductance';
            app.RealInductanceEditField_2 = uieditfield(app.CoilPanel_2, 'numeric');
            app.NturnsouterhelixEditField_2Label = uilabel(app.CoilPanel_2);
            app.NturnsouterhelixEditField_2Label.Text = 'Nº turns outer helix';
            app.NturnsouterhelixEditField_2 = uieditfield(app.CoilPanel_2, 'numeric');
            app.RadiusouterhelixEditField_2Label = uilabel(app.CoilPanel_2);
            app.RadiusouterhelixEditField_2Label.Text = 'Radius outer helix';
            app.RadiusouterhelixEditField_2 = uieditfield(app.CoilPanel_2, 'numeric');
            app.PitchouterhelixEditField_2Label = uilabel(app.CoilPanel_2);
            app.PitchouterhelixEditField_2Label.Text = 'Pitch outer helix';
            app.PitchouterhelixEditField_2 = uieditfield(app.CoilPanel_2, 'numeric');
            app.NturnsinnerhelixEditField_2Label = uilabel(app.CoilPanel_2);
            app.NturnsinnerhelixEditField_2Label.Text = 'Nº turns inner helix';
            app.NturnsinnerhelixEditField_2 = uieditfield(app.CoilPanel_2, 'numeric');
            app.RadiusinnerhelixEditField_2Label = uilabel(app.CoilPanel_2);
            app.RadiusinnerhelixEditField_2Label.Text = 'Radius inner helix';
            app.RadiusinnerhelixEditField_2 = uieditfield(app.CoilPanel_2, 'numeric');
            app.PitchinnerhelixEditField_2Label = uilabel(app.CoilPanel_2);
            app.PitchinnerhelixEditField_2Label.Text = 'Pitch inner helix';
            app.PitchinnerhelixEditField_2 = uieditfield(app.CoilPanel_2, 'numeric');
            app.mLabel_6 = uilabel(app.CoilPanel_2);
            app.mLabel_6.Text = '(m)';
            app.mLabel_7 = uilabel(app.CoilPanel_2);
            app.mLabel_7.Text = '(m)';
            app.HLabel_4 = uilabel(app.CoilPanel_2);
            app.HLabel_4.Text = '(H)';
            app.mLabel_10 = uilabel(app.CoilPanel_2);
            app.mLabel_10.Text = '(m)';
            app.mLabel_11 = uilabel(app.CoilPanel_2);
            app.mLabel_11.Text = '(m)';
            app.mLabel_12 = uilabel(app.CoilPanel_2);
            app.mLabel_12.Text = '(m)';
            app.mLabel_13 = uilabel(app.CoilPanel_2);
            app.mLabel_13.Text = '(m)';
            app.CorePanel_2 = uipanel(app.Panel_12);
            app.CorePanel_2.Title = 'Core';
            app.LengthEditField_2Label = uilabel(app.CorePanel_2);
            app.LengthEditField_2Label.Text = 'Length';
            app.LengthEditField_2 = uieditfield(app.CorePanel_2, 'numeric');
            app.DiameterEditField_2Label = uilabel(app.CorePanel_2);
            app.DiameterEditField_2Label.Text = 'Diameter';
            app.DiameterEditField_2 = uieditfield(app.CorePanel_2, 'numeric');
            app.mLabel_9 = uilabel(app.CorePanel_2);
            app.mLabel_9.Text = '(m)';
            app.mLabel_14 = uilabel(app.CorePanel_2);
            app.mLabel_14.Text = '(m)';
            app.AddNewCoiltoDDBBButton = uibutton(app.Panel_12, 'push');
            app.AddNewCoiltoDDBBButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewCoiltoDDBBButtonPushed, true);
            app.AddNewCoiltoDDBBButton.Text = 'Add New Coil to DDBB';
            app.ResetButton = uibutton(app.Panel_12, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Panel_13 = uipanel(app.Panel_11);
            app.NewCoilNameEditFieldLabel = uilabel(app.Panel_13);
            app.NewCoilNameEditFieldLabel.Text = 'New Coil Name';
            app.NewCoilNameEditField = uieditfield(app.Panel_13, 'text');
            app.Panel_14 = uipanel(app.Panel_11);
            app.NeccessarywhenusingasquaresignalLabel = uilabel(app.Panel_14);
            app.NeccessarywhenusingasquaresignalLabel.Text = '(Neccessary when using a square signal)';
            app.CoilRiseTimeEditFieldLabel = uilabel(app.Panel_14);
            app.CoilRiseTimeEditFieldLabel.Text = 'Coil Rise Time';
            app.CoilRiseTimeEditField = uieditfield(app.Panel_14, 'numeric');
            app.sLabel = uilabel(app.Panel_14);
            app.sLabel.Text = '(s)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appTFM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end